classdef Term2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DataTab                       matlab.ui.container.Tab
        AverageHeightEditField        matlab.ui.control.NumericEditField
        AverageHeightEditFieldLabel   matlab.ui.control.Label
        AverageWeightEditField        matlab.ui.control.NumericEditField
        AverageWeightEditFieldLabel   matlab.ui.control.Label
        ChooseExcelFileButton         matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        BMICalculatorTab              matlab.ui.container.Tab
        CalculateButton               matlab.ui.control.Button
        BMIEditField                  matlab.ui.control.NumericEditField
        BMIEditFieldLabel             matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        WeightEditField               matlab.ui.control.NumericEditField
        WeightEditFieldLabel          matlab.ui.control.Label
        GraphTab                      matlab.ui.container.Tab
        ChooseExcelFiletoGraphButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            bodyWeight=app.WeightEditField.Value;
            bodyHeight=app.HeightEditField.Value;

            BodyMassIndex=(bodyWeight/bodyHeight^2)*10000;

            app.BMIEditField.Value=BodyMassIndex;
        end

        % Button pushed function: ChooseExcelFileButton
        function ChooseExcelFileButtonPushed(app, event)
            [file, path]=uigetfile('*.xlsx');
            Excel=readtable(fullfile(path, file));

            app.UITable.Data=Excel;
            
            avgWeight=readmatrix(fullfile(path, file),"sheet","Sheet1", "range","C2:C26");
            avgHeight=readmatrix(fullfile(path, file),"sheet","Sheet1", "range","D2:D26");
            
            app.AverageWeightEditField.Value=mean(avgWeight);
            app.AverageHeightEditField.Value=mean(avgHeight);
            
        end

        % Button pushed function: ChooseExcelFiletoGraphButton
        function ChooseExcelFiletoGraphButtonPushed(app, event)
            [file, path]=uigetfile('*.xlsx');
            
            avgWeight=readmatrix(fullfile(path, file),"sheet","Sheet1", "range","C2:C26");
            avgHeight=readmatrix(fullfile(path, file),"sheet","Sheet1", "range","D2:D26");
            Age=readmatrix(fullfile(path, file),"sheet","Sheet1", "range","B2:B26");
            
            BMI=(avgWeight/avgHeight.^2)*10000;
            
            
            scatter(app.UIAxes,BMI,Age,'bo')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTab = uitab(app.TabGroup);
            app.DataTab.Title = 'Data';
            app.UITable = uitable(app.DataTab);
            app.UITable.ColumnName = {'Participants'; 'Age'; 'Weight'; 'Height'};
            app.UITable.RowName = {};
            app.ChooseExcelFileButton = uibutton(app.DataTab, 'push');
            app.ChooseExcelFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExcelFileButtonPushed, true);
            app.ChooseExcelFileButton.Text = 'Choose Excel File';
            app.AverageWeightEditFieldLabel = uilabel(app.DataTab);
            app.AverageWeightEditFieldLabel.Text = 'Average Weight';
            app.AverageWeightEditField = uieditfield(app.DataTab, 'numeric');
            app.AverageHeightEditFieldLabel = uilabel(app.DataTab);
            app.AverageHeightEditFieldLabel.Text = 'Average Height';
            app.AverageHeightEditField = uieditfield(app.DataTab, 'numeric');
            app.BMICalculatorTab = uitab(app.TabGroup);
            app.BMICalculatorTab.Title = 'BMI Calculator';
            app.WeightEditFieldLabel = uilabel(app.BMICalculatorTab);
            app.WeightEditFieldLabel.Text = ' Weight ';
            app.WeightEditField = uieditfield(app.BMICalculatorTab, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.BMICalculatorTab);
            app.HeightEditFieldLabel.Text = ' Height ';
            app.HeightEditField = uieditfield(app.BMICalculatorTab, 'numeric');
            app.BMIEditFieldLabel = uilabel(app.BMICalculatorTab);
            app.BMIEditFieldLabel.Text = ' BMI';
            app.BMIEditField = uieditfield(app.BMICalculatorTab, 'numeric');
            app.CalculateButton = uibutton(app.BMICalculatorTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.GraphTab = uitab(app.TabGroup);
            app.GraphTab.Title = 'Graph';
            app.UIAxes = uiaxes(app.GraphTab);
            title(app.UIAxes, 'Scatter Plot')
            xlabel(app.UIAxes, 'BMI')
            ylabel(app.UIAxes, 'Age')
            zlabel(app.UIAxes, 'Z')
            app.ChooseExcelFiletoGraphButton = uibutton(app.GraphTab, 'push');
            app.ChooseExcelFiletoGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseExcelFiletoGraphButtonPushed, true);
            app.ChooseExcelFiletoGraphButton.Text = 'Choose Excel File to Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Term2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end